classdef SSAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SSARUIFigure                  matlab.ui.Figure
        StartButton                   matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ScenarioTab                   matlab.ui.container.Tab
        ScenarioButtonGroup           matlab.ui.container.ButtonGroup
        AButton                       matlab.ui.control.ToggleButton
        BButton                       matlab.ui.control.ToggleButton
        CButton                       matlab.ui.control.ToggleButton
        ClassifierADropDownLabel      matlab.ui.control.Label
        ClassifierADropDown           matlab.ui.control.DropDown
        ClassifierBDropDownLabel      matlab.ui.control.Label
        ClassifierBDropDown           matlab.ui.control.DropDown
        ClassifierCDropDownLabel      matlab.ui.control.Label
        ClassifierCDropDown           matlab.ui.control.DropDown
        DatasetpartitioningEditFieldLabel  matlab.ui.control.Label
        DatasetpartitioningEditField  matlab.ui.control.NumericEditField
        FeatureSelectionTab           matlab.ui.container.Tab
        RemoverawmeasurementsSwitchLabel  matlab.ui.control.Label
        RemoverawmeasurementsSwitch   matlab.ui.control.Switch
        RemovecorrelatedSwitchLabel   matlab.ui.control.Label
        RemovecorrelatedSwitch        matlab.ui.control.Switch
        MaximumCorrelationEditFieldLabel  matlab.ui.control.Label
        MaximumCorrelationEditField   matlab.ui.control.NumericEditField
        FeaturesListBoxLabel          matlab.ui.control.Label
        FeaturesListBox               matlab.ui.control.ListBox
        UpdateButton                  matlab.ui.control.Button
        GadgetButtonGroup             matlab.ui.container.ButtonGroup
        PhoneButton                   matlab.ui.control.ToggleButton
        WatchButton                   matlab.ui.control.ToggleButton
        SensorButtonGroup             matlab.ui.container.ButtonGroup
        AccelButton                   matlab.ui.control.ToggleButton
        GyroButton                    matlab.ui.control.ToggleButton
        FeatureRankingDropDownLabel   matlab.ui.control.Label
        FeatureRankingDropDown        matlab.ui.control.DropDown
        FeatureReductionTab           matlab.ui.container.Tab
        PCAVarianceEditFieldLabel     matlab.ui.control.Label
        PCAVarianceEditField          matlab.ui.control.NumericEditField
        LDAFeaturesEditFieldLabel     matlab.ui.control.Label
        LDAFeaturesEditField          matlab.ui.control.NumericEditField
        MethodDropDownLabel           matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        PCAFeaturesEditFieldLabel     matlab.ui.control.Label
        PCAFeaturesEditField          matlab.ui.control.NumericEditField
        AvgErrorEditFieldLabel        matlab.ui.control.Label
        AvgErrorEditField             matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        StdDevEditFieldLabel          matlab.ui.control.Label
        StdDevEditField               matlab.ui.control.NumericEditField
        TestButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        data_A %Scenario A data
        data_B %Scenario B data
        data_C %Scenario C data
        working_data %pre reduction data
    end
    
    methods (Access = private)
        
        function results = ScenarioA(app, data)
            
            %fileID = fopen ('tests/results.txt','a');
            %formatSpec = '%s %s,%s,%.5f,%.5f\n';
            
            training = app.DatasetpartitioningEditField.Value;
            testing = 1-training;
            
            
            switch app.ClassifierADropDown.Value
                case "Euclidean"
                    [euclidean_model, average_error, standard_dev] = appEuclideanA(app, data, 25, training, testing, 2);
                    disp(euclidean_model);
                    save ("classifiers\euclidean_modelA.mat", "euclidean_model");
                case "Mahalanobis"
                    [mahalanobis_model, average_error, standard_dev] = appMahalanobisA(app, data, 25, training, testing, 2);
                    disp(mahalanobis_model);
                    save ("classifiers\mahalanobis_modelA.mat","mahalanobis_model");
                case "Fisher LDA"
                    [fisher_model, average_error, standard_dev] = appFisherLDA(app, data, 25, training, testing);
                    disp(fisher_model);
                    save ("classifiers\fisher_modelA.mat","fisher_model");
                case "Bayes"
                    [bayes_model, average_error, standard_dev] = appBayes(app, data, 25, training, testing, 2);
                    disp(bayes_model);
                    save ("classifiers\bayes_modelA.mat","bayes_model");
                case "SVM"
                    [svm_model, average_error, standard_dev] = appSVM(app, data, 25, training, testing, 2);
                    disp(svm_model);
                    save("classifiers\svm_modelA.mat","svm_model");
            end


            %fprintf(fileID,formatSpec, app.GadgetButtonGroup.SelectedObject.Text, app.SensorButtonGroup.SelectedObject.Text,app.ClassifierADropDown.Value, average_error, standard_dev);
            
            
            app.AvgErrorEditField.Value=average_error;
            app.StdDevEditField.Value=standard_dev;
            
            %fclose(fileID);
            
        end
        
        function results = ScenarioB(app, data)
            %fileID = fopen ('tests/results.txt','a');
            %formatSpec = '%s %s,%s,%.5f,%.5f\n';
            
            training = app.DatasetpartitioningEditField.Value;
            testing = 1-training;
            
            
            switch app.ClassifierBDropDown.Value
                case "Euclidean"
                    [euclidean_model, average_error, standard_dev] = appEuclideanA(app, data, 25, training, testing, 3);
                    disp(euclidean_model);
                    save ("classifiers\euclidean_modelB.mat", "euclidean_model");
                case "Mahalanobis"
                    [mahalanobis_model, average_error, standard_dev] = appMahalanobisA(app, data, 25, training, testing, 3);
                    disp(mahalanobis_model);
                    save ("classifiers\mahalanobis_modelB.mat","mahalanobis_model");
%                 case "Fisher LDA"
%                     [fisher_model, average_error, standard_dev] = appFisherLDA(app, data, 25, training, testing);
%                     disp(fisher_model)
%                     save ("classifiers\fisher_modelA.mat","fisher_model");
                case "Bayes"
                    [bayes_model, average_error, standard_dev] = appBayes(app, data, 25, training, testing, 3);
                    disp(bayes_model);
                    save ("classifiers\bayes_modelB.mat","bayes_model");
                case "SVM"
                    [svm_model, average_error, standard_dev] = appSVM(app, data, 25, training, testing, 3);
                    disp(svm_model);
                    save("classifiers\svm_modelB.mat","svm_model");
            end


            %fprintf(fileID,formatSpec, app.GadgetButtonGroup.SelectedObject.Text, app.SensorButtonGroup.SelectedObject.Text,app.ClassifierADropDown.Value, average_error, standard_dev);
            
            
            app.AvgErrorEditField.Value=average_error;
            app.StdDevEditField.Value=standard_dev;
            
            %fclose(fileID);
        end
        
        function results = ScenarioC(app, data)
            training = app.DatasetpartitioningEditField.Value;
            testing = 1-training;
            
            
            switch app.ClassifierCDropDown.Value
                case "Euclidean"
                    [euclidean_model, average_error, standard_dev] = appEuclideanA(app, data, 25, training, testing, 18);
                    disp(euclidean_model);
                    save ("classifiers\euclidean_modelC.mat", "euclidean_model");
                case "Mahalanobis"
                    [mahalanobis_model, average_error, standard_dev] = appMahalanobisA(app, data, 25, training, testing, 18);
                    disp(mahalanobis_model);
                    save ("classifiers\mahalanobis_modelC.mat","mahalanobis_model");
%                 case "Fisher LDA"
%                     [fisher_model, average_error, standard_dev] = appFisherLDA(app, data, 25, training, testing);
%                     disp(fisher_model)
%                     save ("classifiers\fisher_modelA.mat","fisher_model");
                case "Bayes"
                    [bayes_model, average_error, standard_dev] = appBayes(app, data, 25, training, testing, 18);
                    disp(bayes_model);
                    save ("classifiers\bayes_modelC.mat","bayes_model");
                case "SVM"
                    [svm_model, average_error, standard_dev] = appSVM(app, data, 25, training, testing, 18);
                    disp(svm_model);
                    save("classifiers\svm_modelC.mat","svm_model");
            end


            %fprintf(fileID,formatSpec, app.GadgetButtonGroup.SelectedObject.Text, app.SensorButtonGroup.SelectedObject.Text,app.ClassifierADropDown.Value, average_error, standard_dev);
            
            
            app.AvgErrorEditField.Value=average_error;
            app.StdDevEditField.Value=standard_dev;
            
            %fclose(fileID);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        if ~exist("tests", 'dir')
            mkdir("tests")
        end
        if ~exist("classifiers", 'dir')
            mkdir("classifiers")
        end
            
            [app.data_A, app.data_B, app.data_C] = appStartup();
            app.working_data = app.data_A(1);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable='off';
            data = app.working_data;
            features = app.FeaturesListBox.Value;
            if ~isempty(features)
                indices = zeros(length(features), 1);
                for i = 1:length(features)
                    indices(i) = find(contains(app.FeaturesListBox.Items, features{i}));
                end
                data.X = data.X(indices, :);
                data.label = data.label(indices);
                data.dim = length(indices);
            end
            
            switch app.MethodDropDown.Value
                case "PCA #feat"
                    data = pcaReduceFeatures(data, app.PCAFeaturesEditField.Value);
                case "LDA"
                    data = ldaReduceFeatures(data, app.LDAFeaturesEditField.Value);
                case "PCA %var"
                    data = pcaReduceFeatures(data, app.PCAVarianceEditField.Value);
            end
            
            switch app.ScenarioButtonGroup.SelectedObject.Text
                case 'A'
                    app.ScenarioA(data)
                case 'B'
                    app.ScenarioB(data)
                case 'C'
                    app.ScenarioC(data)
            end
            
            app.StartButton.Enable='on';
        end

        % Selection changed function: ScenarioButtonGroup
        function ScenarioButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScenarioButtonGroup.SelectedObject;
            if selectedButton.Text == 'A'
                app.working_data = app.data_A;
            elseif selectedButton.Text == 'B'
                app.working_data = app.data_B;
            else
                app.working_data = app.data_C;
            end
            app.StartButton.Enable = false;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            checks = [strcmp(app.GadgetButtonGroup.SelectedObject.Text, 'Phone') strcmp(app.SensorButtonGroup.SelectedObject.Text, 'Accel')];
            autoSelectMethod = app.FeatureRankingDropDown.Value;
            if app.ScenarioButtonGroup.SelectedObject.Text == 'A'
                [app.working_data, items] = appFeatureSelection(app.data_A, app.RemoverawmeasurementsSwitch.Value, app.RemovecorrelatedSwitch.Value, autoSelectMethod, app.MaximumCorrelationEditField.Value, checks);
            elseif app.ScenarioButtonGroup.SelectedObject.Text == 'B'
                [app.working_data, items] = appFeatureSelection(app.data_B, app.RemoverawmeasurementsSwitch.Value, app.RemovecorrelatedSwitch.Value, autoSelectMethod, app.MaximumCorrelationEditField.Value, checks);
            elseif app.ScenarioButtonGroup.SelectedObject.Text == 'C'
                [app.working_data, items] = appFeatureSelection(app.data_C, app.RemoverawmeasurementsSwitch.Value, app.RemovecorrelatedSwitch.Value, autoSelectMethod, app.MaximumCorrelationEditField.Value, checks);
            end
            app.FeaturesListBox.Items = items;
            app.StartButton.Enable = true;
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.TestButton.Enable='off';
            
            
            selection = uiconfirm(app.SSARUIFigure,"Running test configuration may take several minutes.","Confirm execution",'Icon','warning')
            
            if (strcmp(selection,'OK')==1)
                testAll(app.data_A);
            end

            
            app.TestButton.Enable='on';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SSARUIFigure = uifigure('Visible', 'off');
            app.SSARUIFigure.Name = 'SSAR';
            app.StartButton = uibutton(app.SSARUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = {'Start'; ''};
            app.ExitButton = uibutton(app.SSARUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TabGroup = uitabgroup(app.SSARUIFigure);
            app.ScenarioTab = uitab(app.TabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.ScenarioButtonGroup = uibuttongroup(app.ScenarioTab);
            app.ScenarioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScenarioButtonGroupSelectionChanged, true);
            app.ScenarioButtonGroup.Title = 'Scenario';
            app.AButton = uitogglebutton(app.ScenarioButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uitogglebutton(app.ScenarioButtonGroup);
            app.BButton.Text = 'B';
            app.CButton = uitogglebutton(app.ScenarioButtonGroup);
            app.CButton.Text = 'C';
            app.ClassifierADropDownLabel = uilabel(app.ScenarioTab);
            app.ClassifierADropDownLabel.Text = 'Classifier A';
            app.ClassifierADropDown = uidropdown(app.ScenarioTab);
            app.ClassifierADropDown.Items = {'Bayes', 'Euclidean', 'Fisher LDA', 'Mahalanobis', 'SVM'};
            app.ClassifierADropDown.Value = 'Euclidean';
            app.ClassifierBDropDownLabel = uilabel(app.ScenarioTab);
            app.ClassifierBDropDownLabel.Text = 'Classifier B';
            app.ClassifierBDropDown = uidropdown(app.ScenarioTab);
            app.ClassifierBDropDown.Items = {'Bayes', 'Euclidean', 'Mahalanobis', 'SVM'};
            app.ClassifierBDropDown.Value = 'Euclidean';
            app.ClassifierCDropDownLabel = uilabel(app.ScenarioTab);
            app.ClassifierCDropDownLabel.Text = 'Classifier C';
            app.ClassifierCDropDown = uidropdown(app.ScenarioTab);
            app.ClassifierCDropDown.Items = {'Bayes', 'Euclidean', 'Mahalanobis', 'SVM'};
            app.ClassifierCDropDown.Value = 'Euclidean';
            app.DatasetpartitioningEditFieldLabel = uilabel(app.ScenarioTab);
            app.DatasetpartitioningEditFieldLabel.Text = 'Dataset partitioning %';
            app.DatasetpartitioningEditField = uieditfield(app.ScenarioTab, 'numeric');
            app.DatasetpartitioningEditField.Limits = [0 1];
            app.DatasetpartitioningEditField.ValueDisplayFormat = '%1.2f';
            app.DatasetpartitioningEditField.Value = 0.5;
            app.FeatureSelectionTab = uitab(app.TabGroup);
            app.FeatureSelectionTab.Title = 'Feature Selection';
            app.RemoverawmeasurementsSwitchLabel = uilabel(app.FeatureSelectionTab);
            app.RemoverawmeasurementsSwitchLabel.Text = 'Remove raw measurements';
            app.RemoverawmeasurementsSwitch = uiswitch(app.FeatureSelectionTab, 'slider');
            app.RemovecorrelatedSwitchLabel = uilabel(app.FeatureSelectionTab);
            app.RemovecorrelatedSwitchLabel.Text = 'Remove correlated';
            app.RemovecorrelatedSwitch = uiswitch(app.FeatureSelectionTab, 'slider');
            app.MaximumCorrelationEditFieldLabel = uilabel(app.FeatureSelectionTab);
            app.MaximumCorrelationEditFieldLabel.Text = 'Maximum Correlation';
            app.MaximumCorrelationEditField = uieditfield(app.FeatureSelectionTab, 'numeric');
            app.MaximumCorrelationEditField.Limits = [0 1];
            app.MaximumCorrelationEditField.ValueDisplayFormat = '%1.2f';
            app.MaximumCorrelationEditField.Value = 0.5;
            app.FeaturesListBoxLabel = uilabel(app.FeatureSelectionTab);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.FeatureSelectionTab);
            app.FeaturesListBox.Items = {};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Value = {};
            app.UpdateButton = uibutton(app.FeatureSelectionTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.GadgetButtonGroup = uibuttongroup(app.FeatureSelectionTab);
            app.GadgetButtonGroup.Title = 'Gadget';
            app.PhoneButton = uitogglebutton(app.GadgetButtonGroup);
            app.PhoneButton.Text = 'Phone';
            app.PhoneButton.Value = true;
            app.WatchButton = uitogglebutton(app.GadgetButtonGroup);
            app.WatchButton.Text = 'Watch';
            app.SensorButtonGroup = uibuttongroup(app.FeatureSelectionTab);
            app.SensorButtonGroup.Title = 'Sensor';
            app.AccelButton = uitogglebutton(app.SensorButtonGroup);
            app.AccelButton.Text = 'Accel';
            app.AccelButton.Value = true;
            app.GyroButton = uitogglebutton(app.SensorButtonGroup);
            app.GyroButton.Text = 'Gyro';
            app.FeatureRankingDropDownLabel = uilabel(app.FeatureSelectionTab);
            app.FeatureRankingDropDownLabel.Text = 'Feature Ranking';
            app.FeatureRankingDropDown = uidropdown(app.FeatureSelectionTab);
            app.FeatureRankingDropDown.Items = {'KruskalWallis', 'ROC'};
            app.FeatureRankingDropDown.Value = 'KruskalWallis';
            app.FeatureReductionTab = uitab(app.TabGroup);
            app.FeatureReductionTab.Title = 'Feature Reduction';
            app.PCAVarianceEditFieldLabel = uilabel(app.FeatureReductionTab);
            app.PCAVarianceEditFieldLabel.Text = 'PCA Variance';
            app.PCAVarianceEditField = uieditfield(app.FeatureReductionTab, 'numeric');
            app.PCAVarianceEditField.Limits = [0 1];
            app.PCAVarianceEditField.ValueDisplayFormat = '%1.2f';
            app.PCAVarianceEditField.Value = 0.95;
            app.LDAFeaturesEditFieldLabel = uilabel(app.FeatureReductionTab);
            app.LDAFeaturesEditFieldLabel.Text = '# LDA Features';
            app.LDAFeaturesEditField = uieditfield(app.FeatureReductionTab, 'numeric');
            app.LDAFeaturesEditField.Limits = [1 Inf];
            app.LDAFeaturesEditField.RoundFractionalValues = 'on';
            app.LDAFeaturesEditField.ValueDisplayFormat = '%.0f';
            app.LDAFeaturesEditField.Value = 10;
            app.MethodDropDownLabel = uilabel(app.FeatureReductionTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.FeatureReductionTab);
            app.MethodDropDown.Items = {'None', 'LDA', 'PCA %var', 'PCA #feat'};
            app.MethodDropDown.Value = 'None';
            app.PCAFeaturesEditFieldLabel = uilabel(app.FeatureReductionTab);
            app.PCAFeaturesEditFieldLabel.Text = '# PCA Features';
            app.PCAFeaturesEditField = uieditfield(app.FeatureReductionTab, 'numeric');
            app.PCAFeaturesEditField.Limits = [1 Inf];
            app.PCAFeaturesEditField.RoundFractionalValues = 'on';
            app.PCAFeaturesEditField.ValueDisplayFormat = '%.0f';
            app.PCAFeaturesEditField.Value = 10;
            app.AvgErrorEditFieldLabel = uilabel(app.SSARUIFigure);
            app.AvgErrorEditFieldLabel.Text = 'Avg. Error';
            app.AvgErrorEditField = uieditfield(app.SSARUIFigure, 'numeric');
            app.UIAxes = uiaxes(app.SSARUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StdDevEditFieldLabel = uilabel(app.SSARUIFigure);
            app.StdDevEditFieldLabel.Text = 'Std. Dev.';
            app.StdDevEditField = uieditfield(app.SSARUIFigure, 'numeric');
            app.TestButton = uibutton(app.SSARUIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            % Show the figure after all components are created
            app.SSARUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSAR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SSARUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SSARUIFigure)
        end
    end
end